classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TapwithyourfingerLabel       matlab.ui.control.Label
        DONOTBLINKLabel              matlab.ui.control.Label
        ReadytouseLabel              matlab.ui.control.Label
        WaittostartLabel             matlab.ui.control.Label
        ClickhereButton              matlab.ui.control.Button
        SimulationTime0secondsLabel  matlab.ui.control.Label
        StopButton                   matlab.ui.control.Button
        OpenrealtimegraphicsButton   matlab.ui.control.Button
        StartButton                  matlab.ui.control.Button
        OpenSimulinkmodelButton      matlab.ui.control.Button
    end


    properties (Access = private)
        counter                     double % new property
        timerObj
        timerObjWait
        counterData = [];
    end


    methods (Access = public)
        
        function updateCounterLabel(app)
             simTime = get_param('Unicorn_Demo2', 'SimulationTime');
            app.SimulationTime0secondsLabel.Text = ['SimulationTime: ' num2str(simTime) ' seconds'];   
             % Call waitToStart after 10 seconds
            if simTime >= 10
                app.WaittostartLabel.Visible = "off";% Call the waitToStart function
                app.ReadytouseLabel.Visible = "on";
            else
                app.WaittostartLabel.Visible = "on";
                app.ReadytouseLabel.Visible = "off";

            end
            if simTime > 10 && abs(mod(simTime, 5)) <= 0.3 % Check if simTime is higher than 10 and a multiple of 5
   
                app.DONOTBLINKLabel.Visible = "on";


            end
            if simTime > 10 && abs(mod(simTime, 10)) <= 0.3 % Check if simTime is higher than 10 and a multiple of 5
   
                app.DONOTBLINKLabel.Visible = "off";


            end
            % Extract the integer and decimal parts of the simulation time
            simTimeStr = num2str(simTime);
            [intPart, decPart] = strtok(simTimeStr, '.');
        
            % Check if the integer part contains '7'
            if simTime > 10 && contains(intPart, '7') && ~contains(intPart, '70') && ~contains(intPart, '71') && ~contains(intPart, '72') && ~contains(intPart, '73') && ~contains(intPart, '74') && ~contains(intPart, '75') && ~contains(intPart, '76') && ~contains(intPart, '78') && ~contains(intPart, '79')
                % Code to be executed if '7' is present before the comma
                app.TapwithyourfingerLabel.Visible = "on";
            else
                app.TapwithyourfingerLabel.Visible = "off";
            end
            

        end


        function startCounter(app)
             
            app.timerObj = timer('ExecutionMode', 'fixedRate', ...
                'Period', 0.004, ... % update interval in seconds
                'TasksToExecute', str2double(get_param('Unicorn_Demo2', 'StopTime')), ... % total number of counts
                'TimerFcn', @(~,~)updateCounterLabel(app), ...
                'StopFcn', @(~,~)disp('Counter stopped.'));
            
            simTime = get_param('Unicorn_Demo2', 'SimulationTime');
            app.SimulationTime0secondsLabel.Text = ['Simulation Time: ' num2str(simTime) ' seconds'];
                
            start(app.timerObj);
        end

        function saveCounter(app)
             % Load the existing simTimeData.mat file, if it exists
            if exist('simTimeData.mat', 'file') == 2
                load('simTimeData.mat');  % Load the existing data
            else
                simTimeData = [];
            end
        
            % Create a table with column names 'TimeStamp', 'SimulationTime', and 'Counter'
            simTime = get_param('Unicorn_Demo2', 'SimulationTime');
        
            % Append the new value to the existing data
            simTimeData = [simTimeData; simTime];
        
            % Save the updated simTimeData variable to the .mat file
            save('simTimeData.mat', 'simTimeData');
           
        end

        function waitToStart(app)
            simTime = get_param('Unicorn_Demo2', 'SimulationTime');
            if simTime == 10
                app.WaittostartLabel.Text = ['Ready to use']
            end
    
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenSimulinkmodelButton
        function OpenSimulinkmodelButtonPushed(app, event)

            % Load the Simulink model
              open_system('Unicorn_Demo2.slx');


        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            app.ReadytouseLabel.Visible = "off";
            app.DONOTBLINKLabel.Visible = "off";
            app.TapwithyourfingerLabel.Visible = "off";
            set_param('Unicorn_Demo2', 'SimulationCommand', 'stop');
            set_param('Unicorn_Demo2', 'StartTime', '0')
            startCounter(app);
            sim('Unicorn_Demo2');
            % Start the timer object
            start(app.timerObj);


        end

        % Button pushed function: OpenrealtimegraphicsButton
        function OpenrealtimegraphicsButtonPushed(app, event)
            open_system('Unicorn_Demo2/Scope');
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            set_param('Unicorn_Demo2', 'SimulationCommand', 'stop');
            stop(app.timerObj);
        
        end

        % Button pushed function: ClickhereButton
        function ClickhereButtonPushed(app, event)
            app.saveCounter();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OpenSimulinkmodelButton = uibutton(app.UIFigure, 'push');
            app.OpenSimulinkmodelButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSimulinkmodelButtonPushed, true);
            app.OpenSimulinkmodelButton.Text = 'Open Simulink model';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.OpenrealtimegraphicsButton = uibutton(app.UIFigure, 'push');
            app.OpenrealtimegraphicsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenrealtimegraphicsButtonPushed, true);
            app.OpenrealtimegraphicsButton.Text = 'Open real time graphics';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SimulationTime0secondsLabel = uilabel(app.UIFigure);
            app.SimulationTime0secondsLabel.Text = 'SimulationTime: 0 seconds';
            app.ClickhereButton = uibutton(app.UIFigure, 'push');
            app.ClickhereButton.ButtonPushedFcn = createCallbackFcn(app, @ClickhereButtonPushed, true);
            app.ClickhereButton.Text = 'Click here !';
            app.WaittostartLabel = uilabel(app.UIFigure);
            app.WaittostartLabel.Text = 'Wait to start';
            app.ReadytouseLabel = uilabel(app.UIFigure);
            app.ReadytouseLabel.Text = 'Ready to use';
            app.DONOTBLINKLabel = uilabel(app.UIFigure);
            app.DONOTBLINKLabel.Text = 'DO NOT BLINK';
            app.TapwithyourfingerLabel = uilabel(app.UIFigure);
            app.TapwithyourfingerLabel.Text = 'Tap with your finger';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end